classdef plottool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MemristorandRRAMPlotTool        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        MCharacteristicTab              matlab.ui.container.Tab
        ConstitutiveequationPanel       matlab.ui.container.Panel
        ConstSwitch_mchar               matlab.ui.control.Switch
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        CurrentExcitationPanel          matlab.ui.container.Panel
        CurrentSwitch_mchar             matlab.ui.control.Switch
        Image3                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        AxisOptionsPanel                matlab.ui.container.Panel
        tmaxImg_mchar                   matlab.ui.control.Image
        tminImg_mchar                   matlab.ui.control.Image
        ntImg_mchar                     matlab.ui.control.Image
        tminField_mchar                 matlab.ui.control.NumericEditField
        tmaxField_mchar                 matlab.ui.control.NumericEditField
        ntField_mchar                   matlab.ui.control.NumericEditField
        qmaxImg_mchar                   matlab.ui.control.Image
        nqImg_mchar                     matlab.ui.control.Image
        qminImg_mchar                   matlab.ui.control.Image
        qminField_mchar                 matlab.ui.control.NumericEditField
        qmaxField_mchar                 matlab.ui.control.NumericEditField
        nqField_mchar                   matlab.ui.control.NumericEditField
        ShowontimeaxisPanel             matlab.ui.container.Panel
        VoltageCheckBox_mchar           matlab.ui.control.CheckBox
        CurrentCheckBox_mchar           matlab.ui.control.CheckBox
        FluxCheckBox_mchar              matlab.ui.control.CheckBox
        ChargeCheckBox_mchar            matlab.ui.control.CheckBox
        ResistanceCheckBox_mchar        matlab.ui.control.CheckBox
        ShowonchargeaxisPanel           matlab.ui.container.Panel
        FluxCheckBox2_mchar             matlab.ui.control.CheckBox
        ResistanceCheckBox2_mchar       matlab.ui.control.CheckBox
        ExcitationParametersPanel       matlab.ui.container.Panel
        AmpSlider_mchar                 matlab.ui.control.Slider
        MinLabel_5                      matlab.ui.control.Label
        MaxLabel_5                      matlab.ui.control.Label
        CurrentAmplitudeLabel_3         matlab.ui.control.Label
        AmpLabel_mchar                  matlab.ui.control.Label
        MinAmpField_mchar               matlab.ui.control.NumericEditField
        MaxAmpField_mchar               matlab.ui.control.NumericEditField
        AngleSlider_mchar               matlab.ui.control.Slider
        MinLabel_6                      matlab.ui.control.Label
        MaxLabel_6                      matlab.ui.control.Label
        CurrentAngularFrequencyLabel_3  matlab.ui.control.Label
        AngleLabel_mchar                matlab.ui.control.Label
        MinAngleField_mchar             matlab.ui.control.NumericEditField
        MaxAngleField_mchar             matlab.ui.control.NumericEditField
        DefaultsButton_mchar            matlab.ui.control.Button
        RRAMTab                         matlab.ui.container.Tab
        MemristorTechnologyButtonGroup  matlab.ui.container.ButtonGroup
        ChargeControlledMemristorButton  matlab.ui.control.RadioButton
        FluxControlledMemristorButton   matlab.ui.control.RadioButton
        BreakpointPanel                 matlab.ui.container.Panel
        BreakpointField_rram            matlab.ui.control.NumericEditField
        OhmSiemensLabel                 matlab.ui.control.Label
        ExcitationParametersPanel_2     matlab.ui.container.Panel
        AmplitudeSlider_rram            matlab.ui.control.Slider
        MinLabel_3                      matlab.ui.control.Label
        MaxLabel_3                      matlab.ui.control.Label
        ExcitationAmplitudeLabel_rram   matlab.ui.control.Label
        AmpLabel_rram                   matlab.ui.control.Label
        MinAmpField_rram                matlab.ui.control.NumericEditField
        MaxAmpField_rram                matlab.ui.control.NumericEditField
        AngleSlider_rram                matlab.ui.control.Slider
        MinLabel_4                      matlab.ui.control.Label
        MaxLabel_4                      matlab.ui.control.Label
        ExcitationFrequencyLabel_rram   matlab.ui.control.Label
        AngleLabel_rram                 matlab.ui.control.Label
        MinAngleField_rram              matlab.ui.control.NumericEditField
        MaxAngleField_rram              matlab.ui.control.NumericEditField
        AxisOptionsPanel_2              matlab.ui.container.Panel
        tminField_rram                  matlab.ui.control.NumericEditField
        tmaxField_rram                  matlab.ui.control.NumericEditField
        ntField_rram                    matlab.ui.control.NumericEditField
        xminField_rram                  matlab.ui.control.NumericEditField
        xmaxField_rram                  matlab.ui.control.NumericEditField
        nxField_rram                    matlab.ui.control.NumericEditField
        tmaxImg_rram                    matlab.ui.control.Image
        tminImg_rram                    matlab.ui.control.Image
        ntImg_rram                      matlab.ui.control.Image
        xmaxImg_rram                    matlab.ui.control.Image
        nxImg_rram                      matlab.ui.control.Image
        xminImg_rram                    matlab.ui.control.Image
        TopLeftAxisPanel_rram           matlab.ui.container.Panel
        ConstEqCheckBox_rram            matlab.ui.control.CheckBox
        CondResCheckBox_x_rram          matlab.ui.control.CheckBox
        TopRightAxisPanel_rram          matlab.ui.container.Panel
        viHysCheckBox_rram              matlab.ui.control.CheckBox
        R_GHysCheckBox_rram             matlab.ui.control.CheckBox
        BottomLeftAxisPanel_rram        matlab.ui.container.Panel
        ChargeCheckBox1_rram            matlab.ui.control.CheckBox
        CurrentCheckBox1_rram           matlab.ui.control.CheckBox
        FluxCheckBox1_rram              matlab.ui.control.CheckBox
        VoltageCheckBox1_rram           matlab.ui.control.CheckBox
        CondResCheckBox_t1_rram         matlab.ui.control.CheckBox
        BottomRightAxisPanel_rram       matlab.ui.container.Panel
        ChargeCheckBox2_rram            matlab.ui.control.CheckBox
        CurrentCheckBox2_rram           matlab.ui.control.CheckBox
        FluxCheckBox2_rram              matlab.ui.control.CheckBox
        VoltageCheckBox2_rram           matlab.ui.control.CheckBox
        CondResCheckBox_t2_rram         matlab.ui.control.CheckBox
        DefaultsButton_rram             matlab.ui.control.Button
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        TopLeftAxis                     matlab.ui.control.UIAxes
        TopRightAxis                    matlab.ui.control.UIAxes
        BottomLeftAxis                  matlab.ui.control.UIAxes
        BottomRightAxis                 matlab.ui.control.UIAxes
        GridLayout4                     matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        SaveDataButton                  matlab.ui.control.Button
        CloseFiguresButton              matlab.ui.control.Button
        PopOutFiguresButton             matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        AboutButton                     matlab.ui.control.Button
    end

    
    properties (Access = public)
        vals % Structure containing simulated values
    end
    
    properties (Access = public)
        simulationOption % Description
    end
    
    properties (Access = public)
        rramTechnology % Description
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mchar_setdefaults(app);
            app.simulationOption = "mchar"; % Initialise simulation option
            mchar_calcvals(app);
            mchar_plotvals(app, "internal")
            app.rramTechnology = "ChargeControlled"; % Initialise rram technology
            rram_setdefaults(app);
            rram_calcvals(app);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            
            if selectedTab == app.MCharacteristicTab
                app.simulationOption = "mchar";
                mchar_plotvals(app, "internal");
            elseif selectedTab == app.RRAMTab
                app.simulationOption = "rram";
                rram_plotvals(app, "internal");
            end
        end

        % Value changed function: ConstSwitch_mchar
        function ConstSwitch_mcharValueChanged(app, event)
            value = app.ConstSwitch_mchar.Value;
            mchar_calcvals(app);
            mchar_plotvals(app, "internal");
        end

        % Value changed function: CurrentSwitch_mchar
        function CurrentSwitch_mcharValueChanged(app, event)
            value = app.CurrentSwitch_mchar.Value;
            mchar_calcvals(app);
            mchar_plotvals(app, "internal");
        end

        % Value changed function: MinAmpField_mchar
        function MinAmpField_mcharValueChanged(app, event)
            value = app.MinAmpField_mchar.Value;
            if app.MaxAmpField_mchar.Value <= app.MinAmpField_mchar.Value
                errorhandler(app, 1, 'min amplitude', 'max amplitude');
                app.MinAmpField_mchar.Value = app.vals.mchar.Amin;
            elseif value < 0
                errorhandler(app, 2, 'min amplitude');
                app.MinAmpField_mchar.Value = app.vals.mchar.Amin;
            else
                mchar_calcvals(app);
            mchar_plotvals(app, "internal");
            end
        end

        % Value changed function: MaxAmpField_mchar
        function MaxAmpField_mcharValueChanged(app, event)
            value = app.MaxAmpField_mchar.Value;
            if app.MaxAmpField_mchar.Value <= app.MinAmpField_mchar.Value
                errorhandler(app, 1, 'min amplitude', 'max amplitude');
                app.MaxAmpField_mchar.Value = app.vals.mchar.Amax;
            elseif value < 0
                errorhandler(app, 2, 'max amplitude');
                app.MaxAmpField_mchar.Value = app.vals.mchar.Amax;
            else
                mchar_calcvals(app);
                mchar_plotvals(app, "internal");
            end
        end

        % Value changed function: AmpSlider_mchar
        function AmpSlider_mcharValueChanged(app, event)
            app.AmpSlider_mchar.Value = round(app.AmpSlider_mchar.Value, 1);
            value = app.AmpSlider_mchar.Value;
            mchar_calcvals(app);
            mchar_plotvals(app, "internal");
        end

        % Value changed function: MinAngleField_mchar
        function MinAngleField_mcharValueChanged(app, event)
            value = app.MinAngleField_mchar.Value;
            if app.MaxAngleField_mchar.Value <= app.MinAngleField_mchar.Value
                errorhandler(app, 1, 'min angle', 'max angle');
                app.MinAngleField_mchar.Value = app.vals.mchar.Omegamin;
            elseif value < 0
                errorhandler(app, 2, 'min angle');
                app.MinAngleField_mchar.Value = app.vals.mchar.Omegamin;
            else
                mchar_calcvals(app);
                mchar_plotvals(app, "internal");
            end
        end

        % Value changed function: MaxAngleField_mchar
        function MaxAngleField_mcharValueChanged(app, event)
            value = app.MaxAngleField_mchar.Value;
            if app.MaxAngleField_mchar.Value <= app.MinAngleField_mchar.Value
                errorhandler(app, 1, 'min angle', 'max angle');
                app.MaxAngleField_mchar.Value = app.vals.mchar.Omegamax;
            elseif value < 0
                errorhandler(app, 2, 'max angle');
                app.MaxAngleField_mchar.Value = app.vals.mchar.Omegamax;
            else
                mchar_calcvals(app);
                mchar_plotvals(app, "internal");
            end
        end

        % Value changed function: AngleSlider_mchar
        function AngleSlider_mcharValueChanged(app, event)
            value = app.AngleSlider_mchar.Value;
            app.AngleSlider_mchar.Value = round(app.AngleSlider_mchar.Value, 1);
            mchar_calcvals(app);
            mchar_plotvals(app, "internal");
        end

        % Value changed function: tminField_mchar
        function tminField_mcharValueChanged(app, event)
            value = app.tminField_mchar.Value;
            if app.tmaxField_mchar.Value <= app.tminField_mchar.Value
                errorhandler(app, 1, 'tmin', 'tmax');
                app.tminField_mchar.Value = app.vals.mchar.tmin;
            else
                mchar_calcvals(app);
                mchar_plotvals(app, "internal");
            end
        end

        % Value changed function: tmaxField_mchar
        function tmaxField_mcharValueChanged(app, event)
            value = app.tmaxField_mchar.Value;
            if app.tmaxField_mchar.Value <= app.tminField_mchar.Value
                errorhandler(app, 1, 'tmin', 'tmax');
                app.tmaxField_mchar.Value = app.vals.mchar.tmax;
            else
                mchar_calcvals(app);
                mchar_plotvals(app, "internal");
            end
        end

        % Value changed function: ntField_mchar
        function ntField_mcharValueChanged(app, event)
            value = app.ntField_mchar.Value;
            if (round(value) ~= value)||(value < 0)
                errorhandler(app, 3, 'nt');
                app.ntField_mchar.Value = app.vals.mchar.nt;
            else
                mchar_calcvals(app);
                mchar_plotvals(app, "internal");
            end
        end

        % Value changed function: qminField_mchar
        function qminField_mcharValueChanged(app, event)
            value = app.qminField_mchar.Value;
            if app.qmaxField_mchar.Value <= app.qminField_mchar.Value
                errorhandler(app, 1, 'qmin', 'qmax');
                app.qminField_mchar.Value = app.vals.mchar.qmin;
            else
                mchar_calcvals(app);
                mchar_plotvals(app, "internal");
            end
        end

        % Value changed function: qmaxField_mchar
        function qmaxField_mcharValueChanged(app, event)
            value = app.qmaxField_mchar.Value;
            if app.qmaxField_mchar.Value <= app.qminField_mchar.Value
                errorhandler(app, 1, 'qmin', 'qmax');
                app.qmaxField_mchar.Value = app.vals.mchar.qmax;
            else
                mchar_calcvals(app);
                mchar_plotvals(app, "internal");
            end
        end

        % Value changed function: nqField_mchar
        function nqField_mcharValueChanged(app, event)
            value = app.nqField_mchar.Value;
            if (round(value) ~= value)||(value < 0)
                errorhandler(app, 3, 'nq');
                app.nqField_mchar.Value = app.vals.mchar.nq;
            else
                mchar_calcvals(app);
                mchar_plotvals(app, "internal");
            end
        end

        % Value changed function: VoltageCheckBox_mchar
        function VoltageCheckBox_mcharValueChanged(app, event)
            value = app.VoltageCheckBox_mchar.Value;
            mchar_plotvals(app, "internal");
        end

        % Value changed function: CurrentCheckBox_mchar
        function CurrentCheckBox_mcharValueChanged(app, event)
            value = app.CurrentCheckBox_mchar.Value;
            mchar_plotvals(app, "internal");
        end

        % Value changed function: FluxCheckBox_mchar
        function FluxCheckBox_mcharValueChanged(app, event)
            value = app.FluxCheckBox_mchar.Value;
            mchar_plotvals(app, "internal");
        end

        % Value changed function: ChargeCheckBox_mchar
        function ChargeCheckBox_mcharValueChanged(app, event)
            value = app.ChargeCheckBox_mchar.Value;
            mchar_plotvals(app, "internal");
        end

        % Value changed function: ResistanceCheckBox_mchar
        function ResistanceCheckBox_mcharValueChanged(app, event)
            value = app.ResistanceCheckBox_mchar.Value;
            mchar_plotvals(app, "internal");
        end

        % Value changed function: FluxCheckBox2_mchar
        function FluxCheckBox2_mcharValueChanged(app, event)
            value = app.FluxCheckBox2_mchar.Value;
            mchar_plotvals(app, "internal");
        end

        % Value changed function: ResistanceCheckBox2_mchar
        function ResistanceCheckBox2_mcharValueChanged(app, event)
            value = app.ResistanceCheckBox2_mchar.Value;
            mchar_plotvals(app, "internal");
        end

        % Selection changed function: MemristorTechnologyButtonGroup
        function MemristorTechnologyButtonGroupSelectionChanged(app, event)
            selectedButton = app.MemristorTechnologyButtonGroup.SelectedObject;
            
            if selectedButton == app.ChargeControlledMemristorButton
                app.rramTechnology = "ChargeControlled";
            elseif selectedButton == app.FluxControlledMemristorButton
                app.rramTechnology = "FluxControlled";
            end
            
            rram_setlabels(app);
            rram_calcvals(app);
            rram_plotvals(app, "internal");
            
        end

        % Value changed function: BreakpointField_rram
        function BreakpointField_rramValueChanged(app, event)
            value = app.BreakpointField_rram.Value;
            if value < 0
                errorhandler(app, 2, 'Breakpoint');
                app.BreakpointField_rram.Value = app.vals.rram.breakpoint;
            else
                rram_calcvals(app);
                rram_plotvals(app, "internal");
            end
        end

        % Value changed function: MinAmpField_rram
        function MinAmpField_rramValueChanged(app, event)
            value = app.MinAmpField_rram.Value;
            if app.MaxAmpField_rram.Value <= app.MinAmpField_rram.Value
                errorhandler(app, 1, 'min amplitude', 'max amplitude');
                app.MinAmpField_rram.Value = app.vals.rram.Amin;
            elseif value < 0
                errorhandler(app, 2, 'min amplitude');
                app.MinAmpField_rram.Value = app.vals.rram.Amin;
            else
                rram_calcvals(app);
                rram_plotvals(app, "internal");
            end
        end

        % Value changed function: MaxAmpField_rram
        function MaxAmpField_rramValueChanged(app, event)
            value = app.MaxAmpField_rram.Value;
            if app.MaxAmpField_rram.Value <= app.MinAmpField_rram.Value
                errorhandler(app, 1, 'min amplitude', 'max amplitude');
                app.MaxAmpField_rram.Value = app.vals.rram.Amax;
            elseif value < 0
                errorhandler(app, 2, 'max amplitude');
                app.MaxAmpField_rram.Value = app.vals.rram.Amax;
            else
                rram_calcvals(app);
                rram_plotvals(app, "internal");
            end
        end

        % Value changed function: AmplitudeSlider_rram
        function AmplitudeSlider_rramValueChanged(app, event)
            app.AmplitudeSlider_rram.Value = round(app.AmplitudeSlider_rram.Value, 1);
            value = app.AmplitudeSlider_rram.Value;
            rram_calcvals(app);
            rram_plotvals(app, "internal");
        end

        % Value changed function: MinAngleField_rram
        function MinAngleField_rramValueChanged(app, event)
            value = app.MinAngleField_rram.Value;
            if app.MaxAngleField_rram.Value <= app.MinAngleField_rram.Value
                errorhandler(app, 1, 'min angle', 'max angle');
                app.MinAngleField_rram.Value = app.vals.rram.Omegamin;
            elseif value < 0
                errorhandler(app, 2, 'min angle');
                app.MinAngleField_rram.Value = app.vals.rram.Omegamin;
            else
                rram_calcvals(app);
                rram_plotvals(app, "internal");
            end
        end

        % Value changed function: MaxAngleField_rram
        function MaxAngleField_rramValueChanged(app, event)
            value = app.MaxAngleField_rram.Value;
            if app.MaxAngleField_rram.Value <= app.MinAngleField_rram.Value
                errorhandler(app, 1, 'min angle', 'max angle');
                app.MaxAngleField_rram.Value = app.vals.rram.Omegamax;
            elseif value < 0
                errorhandler(app, 2, 'max angle');
                app.MaxAngleField_rram.Value = app.vals.rram.Omegamax;
            else
                rram_calcvals(app);
                rram_plotvals(app, "internal");
            end
        end

        % Value changed function: AngleSlider_rram
        function AngleSlider_rramValueChanged(app, event)
            app.AngleSlider_rram.Value = round(app.AngleSlider_rram.Value, 1);
            value = app.AngleSlider_rram.Value;
            rram_calcvals(app);
            rram_plotvals(app, "internal");
        end

        % Value changed function: tminField_rram
        function tminField_rramValueChanged(app, event)
            value = app.tminField_rram.Value;
            if app.tmaxField_rram.Value <= app.tminField_rram.Value
                errorhandler(app, 1, 'tmin', 'tmax');
                app.tminField_rram.Value = app.vals.rram.tmin;
            else
                rram_calcvals(app);
                rram_plotvals(app, "internal");
            end
        end

        % Value changed function: tmaxField_rram
        function tmaxField_rramValueChanged(app, event)
            value = app.tmaxField_rram.Value;
            if app.tmaxField_rram.Value <= app.tminField_rram.Value
                errorhandler(app, 1, 'tmin', 'tmax');
                app.tmaxField_rram.Value = app.vals.rram.tmax;
            else
                rram_calcvals(app);
                rram_plotvals(app, "internal");
            end
        end

        % Value changed function: ntField_rram
        function ntField_rramValueChanged(app, event)
            value = app.ntField_rram.Value;
            if (round(value) ~= value)||(value < 0)
                errorhandler(app, 3, 'nt');
                app.ntField_rram.Value = app.vals.rram.nt;
            else
                rram_calcvals(app);
                rram_plotvals(app, "internal");
            end
        end

        % Value changed function: xminField_rram
        function xminField_rramValueChanged(app, event)
            value = app.xminField_rram.Value;
            if app.xmaxField_rram.Value <= app.xminField_rram.Value
                errorhandler(app, 1, 'xmin', 'xmax');
                app.xminField_rram.Value = app.vals.rram.xmin;
            else
                rram_calcvals(app);
                rram_plotvals(app, "internal");
            end
        end

        % Value changed function: xmaxField_rram
        function xmaxField_rramValueChanged(app, event)
            value = app.xmaxField_rram.Value;
            if app.xmaxField_rram.Value <= app.xminField_rram.Value
                errorhandler(app, 1, 'xmin', 'xmax');
                app.xmaxField_rram.Value = app.vals.rram.xmax;
            else
                rram_calcvals(app);
                rram_plotvals(app, "internal");
            end
        end

        % Value changed function: nxField_rram
        function nxField_rramValueChanged(app, event)
            value = app.nxField_rram.Value;
            if (round(value) ~= value)||(value < 0)
                errorhandler(app, 3, 'nx');
                app.nxField_rram.Value = app.vals.rram.nx;
            else
                rram_calcvals(app);
                rram_plotvals(app, "internal");
            end
        end

        % Value changed function: ConstEqCheckBox_rram
        function ConstEqCheckBox_rramValueChanged(app, event)
            value = app.ConstEqCheckBox_rram.Value;
            rram_plotvals(app, "internal");
        end

        % Value changed function: CondResCheckBox_x_rram
        function CondResCheckBox_x_rramValueChanged(app, event)
            value = app.CondResCheckBox_x_rram.Value;
            rram_plotvals(app, "internal");
        end

        % Value changed function: viHysCheckBox_rram
        function viHysCheckBox_rramValueChanged(app, event)
            value = app.viHysCheckBox_rram.Value;
            rram_plotvals(app, "internal");
        end

        % Value changed function: R_GHysCheckBox_rram
        function R_GHysCheckBox_rramValueChanged(app, event)
            value = app.R_GHysCheckBox_rram.Value;
            rram_plotvals(app, "internal");
        end

        % Value changed function: ChargeCheckBox1_rram
        function ChargeCheckBox1_rramValueChanged(app, event)
            value = app.ChargeCheckBox1_rram.Value;
            rram_plotvals(app, "internal");
        end

        % Value changed function: CurrentCheckBox1_rram
        function CurrentCheckBox1_rramValueChanged(app, event)
            value = app.CurrentCheckBox1_rram.Value;
            rram_plotvals(app, "internal");
        end

        % Value changed function: FluxCheckBox1_rram
        function FluxCheckBox1_rramValueChanged(app, event)
            value = app.FluxCheckBox1_rram.Value;
            rram_plotvals(app, "internal");
        end

        % Value changed function: VoltageCheckBox1_rram
        function VoltageCheckBox1_rramValueChanged(app, event)
            value = app.VoltageCheckBox1_rram.Value;
            rram_plotvals(app, "internal");
        end

        % Value changed function: CondResCheckBox_t1_rram
        function CondResCheckBox_t1_rramValueChanged(app, event)
            value = app.CondResCheckBox_t1_rram.Value;
            rram_plotvals(app, "internal");
        end

        % Value changed function: ChargeCheckBox2_rram
        function ChargeCheckBox2_rramValueChanged(app, event)
            value = app.ChargeCheckBox2_rram.Value;
            rram_plotvals(app, "internal");
        end

        % Value changed function: CurrentCheckBox2_rram
        function CurrentCheckBox2_rramValueChanged(app, event)
            value = app.CurrentCheckBox2_rram.Value;
            rram_plotvals(app, "internal");
        end

        % Value changed function: FluxCheckBox2_rram
        function FluxCheckBox2_rramValueChanged(app, event)
            value = app.FluxCheckBox2_rram.Value;
            rram_plotvals(app, "internal");
        end

        % Value changed function: VoltageCheckBox2_rram
        function VoltageCheckBox2_rramValueChanged(app, event)
            value = app.VoltageCheckBox2_rram.Value;
            rram_plotvals(app, "internal");
        end

        % Value changed function: CondResCheckBox_t2_rram
        function CondResCheckBox_t2_rramValueChanged(app, event)
            value = app.CondResCheckBox_t2_rram.Value;
            rram_plotvals(app, "internal");
        end

        % Button pushed function: DefaultsButton_rram
        function DefaultsButton_rramPushed(app, event)
            rram_setdefaults(app);
            rram_calcvals(app);
            rram_plotvals(app, "internal");
        end

        % Button pushed function: DefaultsButton_mchar
        function DefaultsButton_mcharPushed(app, event)
            mchar_setdefaults(app);
            mchar_calcvals(app);
            mchar_plotvals(app, "internal");
        end

        % Button pushed function: PopOutFiguresButton
        function PopOutFiguresButtonPushed(app, event)
            if app.simulationOption == "mchar"
                mchar_plotvals(app, "external");
            elseif app.simulationOption == "rram"
                rram_plotvals(app, "external");
            end
        end

        % Button pushed function: CloseFiguresButton
        function CloseFiguresButtonPushed(app, event)
            try
                close(1);
            catch
            end
            try
                close(2);
            catch
            end
            try
                close(3);
            catch
            end
            try
                close(4);
            catch
            end
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            writetofile(app);
        end

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            message = sprintf(['Welcome to the Memristor and RRAM Plot Tool! \n\n ' ...
                'Author: John Smith \n Version: 1.0 \n\n Use this tool to quickly' ...
                ' and easily produce characteristic curves for simple memristors and RRAMs' ...
                ' from basic input parameters, and observe their effect in real-time.']);
            uialert(app.MemristorandRRAMPlotTool, message, 'About', 'Icon', 'info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MemristorandRRAMPlotTool = uifigure('Visible', 'off');
            app.MemristorandRRAMPlotTool.Name = 'Memristor and RRAM Plot Tool';
            app.GridLayout = uigridlayout(app.MemristorandRRAMPlotTool);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MCharacteristicTab = uitab(app.TabGroup);
            app.MCharacteristicTab.Title = 'M Characteristic';
            app.ConstitutiveequationPanel = uipanel(app.MCharacteristicTab);
            app.ConstitutiveequationPanel.Title = 'Constitutive equation';
            app.ConstSwitch_mchar = uiswitch(app.ConstitutiveequationPanel, 'slider');
            app.ConstSwitch_mchar.Items = {'Eq.2', 'Eq.1'};
            app.ConstSwitch_mchar.ValueChangedFcn = createCallbackFcn(app, @ConstSwitch_mcharValueChanged, true);
            app.ConstSwitch_mchar.Value = 'Eq.1';
            app.Image = uiimage(app.ConstitutiveequationPanel);
            app.Image.ImageSource = 'CosntRel1.svg';
            app.Image2 = uiimage(app.ConstitutiveequationPanel);
            app.Image2.ImageSource = 'ConstRel2.svg';
            app.CurrentExcitationPanel = uipanel(app.MCharacteristicTab);
            app.CurrentExcitationPanel.Title = 'Current Excitation';
            app.CurrentSwitch_mchar = uiswitch(app.CurrentExcitationPanel, 'slider');
            app.CurrentSwitch_mchar.Items = {'Eq.2', 'Eq.1'};
            app.CurrentSwitch_mchar.ValueChangedFcn = createCallbackFcn(app, @CurrentSwitch_mcharValueChanged, true);
            app.CurrentSwitch_mchar.Value = 'Eq.1';
            app.Image3 = uiimage(app.CurrentExcitationPanel);
            app.Image3.ImageSource = 'asinwt.svg';
            app.Image4 = uiimage(app.CurrentExcitationPanel);
            app.Image4.ImageSource = 'acoswt.svg';
            app.AxisOptionsPanel = uipanel(app.MCharacteristicTab);
            app.AxisOptionsPanel.Title = 'Axis Options';
            app.tmaxImg_mchar = uiimage(app.AxisOptionsPanel);
            app.tmaxImg_mchar.ImageSource = 'tmax.svg';
            app.tminImg_mchar = uiimage(app.AxisOptionsPanel);
            app.tminImg_mchar.ImageSource = 'tmin.svg';
            app.ntImg_mchar = uiimage(app.AxisOptionsPanel);
            app.ntImg_mchar.ImageSource = 'nt.svg';
            app.tminField_mchar = uieditfield(app.AxisOptionsPanel, 'numeric');
            app.tminField_mchar.ValueChangedFcn = createCallbackFcn(app, @tminField_mcharValueChanged, true);
            app.tmaxField_mchar = uieditfield(app.AxisOptionsPanel, 'numeric');
            app.tmaxField_mchar.ValueChangedFcn = createCallbackFcn(app, @tmaxField_mcharValueChanged, true);
            app.tmaxField_mchar.Value = 12.57;
            app.ntField_mchar = uieditfield(app.AxisOptionsPanel, 'numeric');
            app.ntField_mchar.ValueChangedFcn = createCallbackFcn(app, @ntField_mcharValueChanged, true);
            app.ntField_mchar.Value = 500;
            app.qmaxImg_mchar = uiimage(app.AxisOptionsPanel);
            app.qmaxImg_mchar.ImageSource = 'qmax.svg';
            app.nqImg_mchar = uiimage(app.AxisOptionsPanel);
            app.nqImg_mchar.ImageSource = 'nq.svg';
            app.qminImg_mchar = uiimage(app.AxisOptionsPanel);
            app.qminImg_mchar.ImageSource = 'qmin.svg';
            app.qminField_mchar = uieditfield(app.AxisOptionsPanel, 'numeric');
            app.qminField_mchar.ValueChangedFcn = createCallbackFcn(app, @qminField_mcharValueChanged, true);
            app.qminField_mchar.Value = -2;
            app.qmaxField_mchar = uieditfield(app.AxisOptionsPanel, 'numeric');
            app.qmaxField_mchar.ValueChangedFcn = createCallbackFcn(app, @qmaxField_mcharValueChanged, true);
            app.qmaxField_mchar.Value = 2;
            app.nqField_mchar = uieditfield(app.AxisOptionsPanel, 'numeric');
            app.nqField_mchar.ValueChangedFcn = createCallbackFcn(app, @nqField_mcharValueChanged, true);
            app.nqField_mchar.Value = 500;
            app.ShowontimeaxisPanel = uipanel(app.MCharacteristicTab);
            app.ShowontimeaxisPanel.Title = 'Show on time axis';
            app.VoltageCheckBox_mchar = uicheckbox(app.ShowontimeaxisPanel);
            app.VoltageCheckBox_mchar.ValueChangedFcn = createCallbackFcn(app, @VoltageCheckBox_mcharValueChanged, true);
            app.VoltageCheckBox_mchar.Text = 'Voltage';
            app.VoltageCheckBox_mchar.Value = true;
            app.CurrentCheckBox_mchar = uicheckbox(app.ShowontimeaxisPanel);
            app.CurrentCheckBox_mchar.ValueChangedFcn = createCallbackFcn(app, @CurrentCheckBox_mcharValueChanged, true);
            app.CurrentCheckBox_mchar.Text = 'Current';
            app.FluxCheckBox_mchar = uicheckbox(app.ShowontimeaxisPanel);
            app.FluxCheckBox_mchar.ValueChangedFcn = createCallbackFcn(app, @FluxCheckBox_mcharValueChanged, true);
            app.FluxCheckBox_mchar.Text = 'Flux';
            app.FluxCheckBox_mchar.Value = true;
            app.ChargeCheckBox_mchar = uicheckbox(app.ShowontimeaxisPanel);
            app.ChargeCheckBox_mchar.ValueChangedFcn = createCallbackFcn(app, @ChargeCheckBox_mcharValueChanged, true);
            app.ChargeCheckBox_mchar.Text = 'Charge';
            app.ResistanceCheckBox_mchar = uicheckbox(app.ShowontimeaxisPanel);
            app.ResistanceCheckBox_mchar.ValueChangedFcn = createCallbackFcn(app, @ResistanceCheckBox_mcharValueChanged, true);
            app.ResistanceCheckBox_mchar.Text = 'Resistance';
            app.ShowonchargeaxisPanel = uipanel(app.MCharacteristicTab);
            app.ShowonchargeaxisPanel.Title = 'Show on charge axis';
            app.FluxCheckBox2_mchar = uicheckbox(app.ShowonchargeaxisPanel);
            app.FluxCheckBox2_mchar.ValueChangedFcn = createCallbackFcn(app, @FluxCheckBox2_mcharValueChanged, true);
            app.FluxCheckBox2_mchar.Text = 'Flux';
            app.FluxCheckBox2_mchar.Value = true;
            app.ResistanceCheckBox2_mchar = uicheckbox(app.ShowonchargeaxisPanel);
            app.ResistanceCheckBox2_mchar.ValueChangedFcn = createCallbackFcn(app, @ResistanceCheckBox2_mcharValueChanged, true);
            app.ResistanceCheckBox2_mchar.Text = 'Resistance';
            app.ExcitationParametersPanel = uipanel(app.MCharacteristicTab);
            app.ExcitationParametersPanel.Title = 'Excitation Current Parameters';
            app.AmpSlider_mchar = uislider(app.ExcitationParametersPanel);
            app.AmpSlider_mchar.Limits = [0 1];
            app.AmpSlider_mchar.MajorTicks = [];
            app.AmpSlider_mchar.ValueChangedFcn = createCallbackFcn(app, @AmpSlider_mcharValueChanged, true);
            app.AmpSlider_mchar.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AmpSlider_mchar.Value = 0.5;
            app.MinLabel_5 = uilabel(app.ExcitationParametersPanel);
            app.MinLabel_5.Text = 'Min';
            app.MaxLabel_5 = uilabel(app.ExcitationParametersPanel);
            app.MaxLabel_5.Text = 'Max';
            app.CurrentAmplitudeLabel_3 = uilabel(app.ExcitationParametersPanel);
            app.CurrentAmplitudeLabel_3.Text = {'Current '; 'Amplitude'};
            app.AmpLabel_mchar = uilabel(app.ExcitationParametersPanel);
            app.MinAmpField_mchar = uieditfield(app.ExcitationParametersPanel, 'numeric');
            app.MinAmpField_mchar.ValueChangedFcn = createCallbackFcn(app, @MinAmpField_mcharValueChanged, true);
            app.MaxAmpField_mchar = uieditfield(app.ExcitationParametersPanel, 'numeric');
            app.MaxAmpField_mchar.ValueChangedFcn = createCallbackFcn(app, @MaxAmpField_mcharValueChanged, true);
            app.MaxAmpField_mchar.Value = 2;
            app.AngleSlider_mchar = uislider(app.ExcitationParametersPanel);
            app.AngleSlider_mchar.Limits = [0 1];
            app.AngleSlider_mchar.MajorTicks = [];
            app.AngleSlider_mchar.MajorTickLabels = {''};
            app.AngleSlider_mchar.ValueChangedFcn = createCallbackFcn(app, @AngleSlider_mcharValueChanged, true);
            app.AngleSlider_mchar.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AngleSlider_mchar.Value = 0.5;
            app.MinLabel_6 = uilabel(app.ExcitationParametersPanel);
            app.MinLabel_6.Text = 'Min';
            app.MaxLabel_6 = uilabel(app.ExcitationParametersPanel);
            app.MaxLabel_6.Text = 'Max';
            app.CurrentAngularFrequencyLabel_3 = uilabel(app.ExcitationParametersPanel);
            app.CurrentAngularFrequencyLabel_3.Text = {'Current '; 'Angular Frequency'};
            app.AngleLabel_mchar = uilabel(app.ExcitationParametersPanel);
            app.MinAngleField_mchar = uieditfield(app.ExcitationParametersPanel, 'numeric');
            app.MinAngleField_mchar.ValueChangedFcn = createCallbackFcn(app, @MinAngleField_mcharValueChanged, true);
            app.MaxAngleField_mchar = uieditfield(app.ExcitationParametersPanel, 'numeric');
            app.MaxAngleField_mchar.ValueChangedFcn = createCallbackFcn(app, @MaxAngleField_mcharValueChanged, true);
            app.MaxAngleField_mchar.Value = 2;
            app.DefaultsButton_mchar = uibutton(app.MCharacteristicTab, 'push');
            app.DefaultsButton_mchar.ButtonPushedFcn = createCallbackFcn(app, @DefaultsButton_mcharPushed, true);
            app.DefaultsButton_mchar.Text = 'Default Settings';
            app.RRAMTab = uitab(app.TabGroup);
            app.RRAMTab.Title = 'RRAM';
            app.MemristorTechnologyButtonGroup = uibuttongroup(app.RRAMTab);
            app.MemristorTechnologyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MemristorTechnologyButtonGroupSelectionChanged, true);
            app.MemristorTechnologyButtonGroup.Title = 'Memristor Technology';
            app.ChargeControlledMemristorButton = uiradiobutton(app.MemristorTechnologyButtonGroup);
            app.ChargeControlledMemristorButton.Text = 'Charge Controlled Memristor';
            app.ChargeControlledMemristorButton.Value = true;
            app.FluxControlledMemristorButton = uiradiobutton(app.MemristorTechnologyButtonGroup);
            app.FluxControlledMemristorButton.Text = 'Flux Controlled Memristor';
            app.BreakpointPanel = uipanel(app.RRAMTab);
            app.BreakpointPanel.Title = 'Breakpoint';
            app.BreakpointField_rram = uieditfield(app.BreakpointPanel, 'numeric');
            app.BreakpointField_rram.ValueChangedFcn = createCallbackFcn(app, @BreakpointField_rramValueChanged, true);
            app.BreakpointField_rram.Value = 1;
            app.OhmSiemensLabel = uilabel(app.BreakpointPanel);
            app.OhmSiemensLabel.Text = 'Ohm/Siemens';
            app.ExcitationParametersPanel_2 = uipanel(app.RRAMTab);
            app.ExcitationParametersPanel_2.Title = 'Excitation Current Parameters';
            app.AmplitudeSlider_rram = uislider(app.ExcitationParametersPanel_2);
            app.AmplitudeSlider_rram.Limits = [0 1];
            app.AmplitudeSlider_rram.MajorTicks = [];
            app.AmplitudeSlider_rram.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSlider_rramValueChanged, true);
            app.AmplitudeSlider_rram.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AmplitudeSlider_rram.Value = 0.5;
            app.MinLabel_3 = uilabel(app.ExcitationParametersPanel_2);
            app.MinLabel_3.Text = 'Min';
            app.MaxLabel_3 = uilabel(app.ExcitationParametersPanel_2);
            app.MaxLabel_3.Text = 'Max';
            app.ExcitationAmplitudeLabel_rram = uilabel(app.ExcitationParametersPanel_2);
            app.ExcitationAmplitudeLabel_rram.Text = {'Current '; 'Amplitude'};
            app.AmpLabel_rram = uilabel(app.ExcitationParametersPanel_2);
            app.MinAmpField_rram = uieditfield(app.ExcitationParametersPanel_2, 'numeric');
            app.MinAmpField_rram.ValueChangedFcn = createCallbackFcn(app, @MinAmpField_rramValueChanged, true);
            app.MaxAmpField_rram = uieditfield(app.ExcitationParametersPanel_2, 'numeric');
            app.MaxAmpField_rram.ValueChangedFcn = createCallbackFcn(app, @MaxAmpField_rramValueChanged, true);
            app.MaxAmpField_rram.Value = 2;
            app.AngleSlider_rram = uislider(app.ExcitationParametersPanel_2);
            app.AngleSlider_rram.Limits = [0 1];
            app.AngleSlider_rram.MajorTicks = [];
            app.AngleSlider_rram.MajorTickLabels = {''};
            app.AngleSlider_rram.ValueChangedFcn = createCallbackFcn(app, @AngleSlider_rramValueChanged, true);
            app.AngleSlider_rram.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.AngleSlider_rram.Value = 0.5;
            app.MinLabel_4 = uilabel(app.ExcitationParametersPanel_2);
            app.MinLabel_4.Text = 'Min';
            app.MaxLabel_4 = uilabel(app.ExcitationParametersPanel_2);
            app.MaxLabel_4.Text = 'Max';
            app.ExcitationFrequencyLabel_rram = uilabel(app.ExcitationParametersPanel_2);
            app.ExcitationFrequencyLabel_rram.Text = {'Current '; 'Angular Frequency'};
            app.AngleLabel_rram = uilabel(app.ExcitationParametersPanel_2);
            app.MinAngleField_rram = uieditfield(app.ExcitationParametersPanel_2, 'numeric');
            app.MinAngleField_rram.ValueChangedFcn = createCallbackFcn(app, @MinAngleField_rramValueChanged, true);
            app.MaxAngleField_rram = uieditfield(app.ExcitationParametersPanel_2, 'numeric');
            app.MaxAngleField_rram.ValueChangedFcn = createCallbackFcn(app, @MaxAngleField_rramValueChanged, true);
            app.MaxAngleField_rram.Value = 2;
            app.AxisOptionsPanel_2 = uipanel(app.RRAMTab);
            app.AxisOptionsPanel_2.Title = 'Axis Options';
            app.tminField_rram = uieditfield(app.AxisOptionsPanel_2, 'numeric');
            app.tminField_rram.ValueChangedFcn = createCallbackFcn(app, @tminField_rramValueChanged, true);
            app.tmaxField_rram = uieditfield(app.AxisOptionsPanel_2, 'numeric');
            app.tmaxField_rram.ValueChangedFcn = createCallbackFcn(app, @tmaxField_rramValueChanged, true);
            app.tmaxField_rram.Value = 6.28;
            app.ntField_rram = uieditfield(app.AxisOptionsPanel_2, 'numeric');
            app.ntField_rram.ValueChangedFcn = createCallbackFcn(app, @ntField_rramValueChanged, true);
            app.ntField_rram.Value = 500;
            app.xminField_rram = uieditfield(app.AxisOptionsPanel_2, 'numeric');
            app.xminField_rram.ValueChangedFcn = createCallbackFcn(app, @xminField_rramValueChanged, true);
            app.xminField_rram.Value = -2;
            app.xmaxField_rram = uieditfield(app.AxisOptionsPanel_2, 'numeric');
            app.xmaxField_rram.ValueChangedFcn = createCallbackFcn(app, @xmaxField_rramValueChanged, true);
            app.xmaxField_rram.Value = 2;
            app.nxField_rram = uieditfield(app.AxisOptionsPanel_2, 'numeric');
            app.nxField_rram.ValueChangedFcn = createCallbackFcn(app, @nxField_rramValueChanged, true);
            app.nxField_rram.Value = 500;
            app.tmaxImg_rram = uiimage(app.AxisOptionsPanel_2);
            app.tmaxImg_rram.ImageSource = 'tmax.svg';
            app.tminImg_rram = uiimage(app.AxisOptionsPanel_2);
            app.tminImg_rram.ImageSource = 'tmin.svg';
            app.ntImg_rram = uiimage(app.AxisOptionsPanel_2);
            app.ntImg_rram.ImageSource = 'nt.svg';
            app.xmaxImg_rram = uiimage(app.AxisOptionsPanel_2);
            app.xmaxImg_rram.ImageSource = 'qmax.svg';
            app.nxImg_rram = uiimage(app.AxisOptionsPanel_2);
            app.nxImg_rram.ImageSource = 'nq.svg';
            app.xminImg_rram = uiimage(app.AxisOptionsPanel_2);
            app.xminImg_rram.ImageSource = 'qmin.svg';
            app.TopLeftAxisPanel_rram = uipanel(app.RRAMTab);
            app.TopLeftAxisPanel_rram.Title = 'Charge Axis';
            app.ConstEqCheckBox_rram = uicheckbox(app.TopLeftAxisPanel_rram);
            app.ConstEqCheckBox_rram.ValueChangedFcn = createCallbackFcn(app, @ConstEqCheckBox_rramValueChanged, true);
            app.ConstEqCheckBox_rram.Text = {'Constitutive '; 'Relation'};
            app.ConstEqCheckBox_rram.Value = true;
            app.CondResCheckBox_x_rram = uicheckbox(app.TopLeftAxisPanel_rram);
            app.CondResCheckBox_x_rram.ValueChangedFcn = createCallbackFcn(app, @CondResCheckBox_x_rramValueChanged, true);
            app.CondResCheckBox_x_rram.Text = 'Resistance';
            app.TopRightAxisPanel_rram = uipanel(app.RRAMTab);
            app.TopRightAxisPanel_rram.Title = 'Current Axis';
            app.viHysCheckBox_rram = uicheckbox(app.TopRightAxisPanel_rram);
            app.viHysCheckBox_rram.ValueChangedFcn = createCallbackFcn(app, @viHysCheckBox_rramValueChanged, true);
            app.viHysCheckBox_rram.Text = {'v(t), i(t) '; 'Hysteresis'};
            app.viHysCheckBox_rram.Value = true;
            app.R_GHysCheckBox_rram = uicheckbox(app.TopRightAxisPanel_rram);
            app.R_GHysCheckBox_rram.ValueChangedFcn = createCallbackFcn(app, @R_GHysCheckBox_rramValueChanged, true);
            app.R_GHysCheckBox_rram.Text = 'R Hysteresis';
            app.BottomLeftAxisPanel_rram = uipanel(app.RRAMTab);
            app.BottomLeftAxisPanel_rram.Title = 'Time Axis 1';
            app.ChargeCheckBox1_rram = uicheckbox(app.BottomLeftAxisPanel_rram);
            app.ChargeCheckBox1_rram.ValueChangedFcn = createCallbackFcn(app, @ChargeCheckBox1_rramValueChanged, true);
            app.ChargeCheckBox1_rram.Text = 'Charge';
            app.ChargeCheckBox1_rram.Value = true;
            app.CurrentCheckBox1_rram = uicheckbox(app.BottomLeftAxisPanel_rram);
            app.CurrentCheckBox1_rram.ValueChangedFcn = createCallbackFcn(app, @CurrentCheckBox1_rramValueChanged, true);
            app.CurrentCheckBox1_rram.Text = 'Current';
            app.CurrentCheckBox1_rram.Value = true;
            app.FluxCheckBox1_rram = uicheckbox(app.BottomLeftAxisPanel_rram);
            app.FluxCheckBox1_rram.ValueChangedFcn = createCallbackFcn(app, @FluxCheckBox1_rramValueChanged, true);
            app.FluxCheckBox1_rram.Text = 'Flux';
            app.VoltageCheckBox1_rram = uicheckbox(app.BottomLeftAxisPanel_rram);
            app.VoltageCheckBox1_rram.ValueChangedFcn = createCallbackFcn(app, @VoltageCheckBox1_rramValueChanged, true);
            app.VoltageCheckBox1_rram.Text = 'Voltage';
            app.CondResCheckBox_t1_rram = uicheckbox(app.BottomLeftAxisPanel_rram);
            app.CondResCheckBox_t1_rram.ValueChangedFcn = createCallbackFcn(app, @CondResCheckBox_t1_rramValueChanged, true);
            app.CondResCheckBox_t1_rram.Text = 'Resistance';
            app.BottomRightAxisPanel_rram = uipanel(app.RRAMTab);
            app.BottomRightAxisPanel_rram.Title = 'Time Axis 2';
            app.ChargeCheckBox2_rram = uicheckbox(app.BottomRightAxisPanel_rram);
            app.ChargeCheckBox2_rram.ValueChangedFcn = createCallbackFcn(app, @ChargeCheckBox2_rramValueChanged, true);
            app.ChargeCheckBox2_rram.Text = 'Charge';
            app.CurrentCheckBox2_rram = uicheckbox(app.BottomRightAxisPanel_rram);
            app.CurrentCheckBox2_rram.ValueChangedFcn = createCallbackFcn(app, @CurrentCheckBox2_rramValueChanged, true);
            app.CurrentCheckBox2_rram.Text = 'Current';
            app.FluxCheckBox2_rram = uicheckbox(app.BottomRightAxisPanel_rram);
            app.FluxCheckBox2_rram.ValueChangedFcn = createCallbackFcn(app, @FluxCheckBox2_rramValueChanged, true);
            app.FluxCheckBox2_rram.Text = 'Flux';
            app.FluxCheckBox2_rram.Value = true;
            app.VoltageCheckBox2_rram = uicheckbox(app.BottomRightAxisPanel_rram);
            app.VoltageCheckBox2_rram.ValueChangedFcn = createCallbackFcn(app, @VoltageCheckBox2_rramValueChanged, true);
            app.VoltageCheckBox2_rram.Text = 'Voltage';
            app.VoltageCheckBox2_rram.Value = true;
            app.CondResCheckBox_t2_rram = uicheckbox(app.BottomRightAxisPanel_rram);
            app.CondResCheckBox_t2_rram.ValueChangedFcn = createCallbackFcn(app, @CondResCheckBox_t2_rramValueChanged, true);
            app.CondResCheckBox_t2_rram.Text = 'Resistance';
            app.DefaultsButton_rram = uibutton(app.RRAMTab, 'push');
            app.DefaultsButton_rram.ButtonPushedFcn = createCallbackFcn(app, @DefaultsButton_rramPushed, true);
            app.DefaultsButton_rram.Tooltip = {''};
            app.DefaultsButton_rram.Text = 'Default Settings for Technology';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.TopLeftAxis = uiaxes(app.GridLayout3);
            title(app.TopLeftAxis, '')
            xlabel(app.TopLeftAxis, '')
            ylabel(app.TopLeftAxis, '')
            app.TopRightAxis = uiaxes(app.GridLayout3);
            title(app.TopRightAxis, '')
            xlabel(app.TopRightAxis, '')
            ylabel(app.TopRightAxis, '')
            app.BottomLeftAxis = uiaxes(app.GridLayout3);
            title(app.BottomLeftAxis, '')
            xlabel(app.BottomLeftAxis, '')
            ylabel(app.BottomLeftAxis, '')
            app.BottomRightAxis = uiaxes(app.GridLayout3);
            title(app.BottomRightAxis, '')
            xlabel(app.BottomRightAxis, '')
            ylabel(app.BottomRightAxis, '')
            app.GridLayout4 = uigridlayout(app.GridLayout2);
            app.Panel = uipanel(app.GridLayout4);
            app.SaveDataButton = uibutton(app.Panel, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Tooltip = {'Save simulation data to .csv file'};
            app.SaveDataButton.Text = 'Save Data';
            app.CloseFiguresButton = uibutton(app.Panel, 'push');
            app.CloseFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @CloseFiguresButtonPushed, true);
            app.CloseFiguresButton.Tooltip = {'Close all open figures'};
            app.CloseFiguresButton.Text = 'Close Figures';
            app.PopOutFiguresButton = uibutton(app.Panel, 'push');
            app.PopOutFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @PopOutFiguresButtonPushed, true);
            app.PopOutFiguresButton.Tooltip = {'Display axes in figure windows'};
            app.PopOutFiguresButton.Text = 'Pop Out Figures';
            app.Panel_2 = uipanel(app.GridLayout4);
            app.AboutButton = uibutton(app.Panel_2, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Tooltip = {'About the applet'};
            app.AboutButton.Text = 'About';
            % Show the figure after all components are created
            app.MemristorandRRAMPlotTool.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plottool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MemristorandRRAMPlotTool)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MemristorandRRAMPlotTool)
        end
    end
end